classdef congestionGUIEx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentialCongestionEditingUIFigure  matlab.ui.Figure
        ClubCongestionDeltaEditFieldLabel  matlab.ui.control.Label
        ClubDeltaEditField             matlab.ui.control.NumericEditField
        ClubCongestionaEditFieldLabel  matlab.ui.control.Label
        ClubaEditField                 matlab.ui.control.NumericEditField
        IndividualCongestionDeltaEditFieldLabel  matlab.ui.control.Label
        IndDeltaEditField              matlab.ui.control.NumericEditField
        IndvidualCongestionaEditFieldLabel  matlab.ui.control.Label
        IndaEditField                  matlab.ui.control.NumericEditField
        OK                             matlab.ui.control.Button
    end


    properties (Access = private)
        myconfig % Description
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
            app.myconfig = config;
            if ~strcmp(config.Model.club_congestion_function,'exponential')
                diff1 = app.IndDeltaEditField.Position(1) - app.IndividualCongestionDeltaEditFieldLabel.Position(1);
                diff2 = app.IndaEditField.Position(1) -  app.IndvidualCongestionaEditFieldLabel.Position(1);
                x = app.ClubCongestionDeltaEditFieldLabel.Position(1);
                delete(app.ClubCongestionDeltaEditFieldLabel);
                delete(app.ClubDeltaEditField);
                delete(app.ClubCongestionaEditFieldLabel);
                delete(app.ClubaEditField);
                app.IndividualCongestionDeltaEditFieldLabel.Position(1) = x;
                app.IndDeltaEditField.Position(1) = x+diff1;
                app.IndvidualCongestionaEditFieldLabel.Position(1) = x;
                app.IndaEditField.Position(1) = x+diff2;
                app.myconfig.Model.club_congestion_delta = 0;
                app.myconfig.Model.club_congestion_a = 0;
            end
            if ~strcmp(config.Model.individual_congestion_function,'exponential')
                delete(app.IndDeltaEditField)
                delete(app.IndividualCongestionDeltaEditFieldLabel)
                delete(app.IndaEditField)
                delete(app.IndvidualCongestionaEditFieldLabel)
            end
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            if strcmp(app.myconfig.Model.club_congestion_function,'exponential')
                if (app.ClubaEditField.Value+app.ClubDeltaEditField.Value >= 1)
                    msgbox('Invalid Usage - a + delta must be smaller than 1')
                    return
                end
                app.myconfig.Model.club_congestion_delta = app.ClubDeltaEditField.Value;
                app.myconfig.Model.club_congestion_a = app.ClubaEditField.Value;
            end
            if strcmp(app.myconfig.Model.individual_congestion_function,'exponential')
                if (app.IndDeltaEditField.Value+app.IndaEditField.Value >= 1)
                    msgbox('Invalid Usage - a + delta must be smaller than 1')
                    return
                end
                app.myconfig.Model.individual_congestion_delta = app.IndDeltaEditField.Value;
                app.myconfig.Model.individual_congestion_a = app.IndaEditField.Value;
            end
            runExhaustiveScan(app.myconfig);
            closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentialCongestionEditingUIFigure = uifigure;
            app.ExponentialCongestionEditingUIFigure.Name = 'Exponential Congestion Editing';
            app.ClubCongestionDeltaEditFieldLabel = uilabel(app.ExponentialCongestionEditingUIFigure);
            app.ClubCongestionDeltaEditFieldLabel.Text = 'Club Congestion Delta';
            app.ClubDeltaEditField = uieditfield(app.ExponentialCongestionEditingUIFigure, 'numeric');
            app.ClubDeltaEditField.Limits = [0 1];
            app.ClubCongestionaEditFieldLabel = uilabel(app.ExponentialCongestionEditingUIFigure);
            app.ClubCongestionaEditFieldLabel.Text = 'Club Congestion a ';
            app.ClubaEditField = uieditfield(app.ExponentialCongestionEditingUIFigure, 'numeric');
            app.ClubaEditField.Limits = [0 1];
            app.IndividualCongestionDeltaEditFieldLabel = uilabel(app.ExponentialCongestionEditingUIFigure);
            app.IndividualCongestionDeltaEditFieldLabel.Text = 'Individual Congestion Delta';
            app.IndDeltaEditField = uieditfield(app.ExponentialCongestionEditingUIFigure, 'numeric');
            app.IndDeltaEditField.Limits = [0 1];
            app.IndvidualCongestionaEditFieldLabel = uilabel(app.ExponentialCongestionEditingUIFigure);
            app.IndvidualCongestionaEditFieldLabel.Text = 'Indvidual Congestion a ';
            app.IndaEditField = uieditfield(app.ExponentialCongestionEditingUIFigure, 'numeric');
            app.IndaEditField.Limits = [0 1];
            app.OK = uibutton(app.ExponentialCongestionEditingUIFigure, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = congestionGUIEx(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentialCongestionEditingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentialCongestionEditingUIFigure)
        end
    end
end