classdef mainGuiGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                    matlab.ui.Figure
        modelLabel                      matlab.ui.control.Label
        ClubCongestionTypeDropDownLabel  matlab.ui.control.Label
        ClubCongestion                  matlab.ui.control.DropDown
        IndividualCongestionTypeDropDownLabel  matlab.ui.control.Label
        IndividualCongestion            matlab.ui.control.DropDown
        GeodesicDistanceEditFieldLabel  matlab.ui.control.Label
        GeodesicDistance                matlab.ui.control.NumericEditField
        ProcessingLabel                 matlab.ui.control.Label
        ClubwiseStabilityDropDownLabel  matlab.ui.control.Label
        ClubwiseStability               matlab.ui.control.DropDown
        EnvironmentLabel                matlab.ui.control.Label
        NumberOfAgentsEditFieldLabel    matlab.ui.control.Label
        NumberOfAgents                  matlab.ui.control.NumericEditField
        OKButton                        matlab.ui.control.Button
        MembershipCostEditFieldLabel    matlab.ui.control.Label
        MembershipCost                  matlab.ui.control.NumericEditField
        ModelTypeLabel                  matlab.ui.control.Label
        ModelT                          matlab.ui.control.DropDown
        GenerationsLabel                matlab.ui.control.Label
        genNumber                       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        myConfig = demoConfig();     % Description
    end

    methods (Access = private)
            
    end


    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.myConfig.Model.club_congestion_function = app.ClubCongestion.Value;
            app.myConfig.Model.individual_congestion_function = app.IndividualCongestion.Value;
            value = app.ModelT.Value;
            if strcmp(value,'First')
                app.myConfig.Model.individual_congestion_function = 'none';
            elseif  strcmp(value,'Second')
                app.myConfig.Model.club_congestion_function = 'none';
            end

            app.myConfig.Model.geodesic_distance = app.GeodesicDistance.Value;
            app.myConfig.Environment.number_of_agents = app.NumberOfAgents.Value;
            app.myConfig.Model.club_membership_cost = app.MembershipCost.Value;
            app.myConfig.Processing.clubwise_stability = app.ClubwiseStability.Value;
            app.myConfig.Processing.max_number_of_generations = app.genNumber.Value;
            [file,path] = uiputfile('.xlsx');
            app.myConfig.Environment.input_filename = file;
            app.myConfig.Processing.target_folder = path;
            if app.myConfig.Model.geodesic_distance == inf
                app.myConfig.Model.geodesic_distance = app.myConfig.Environment.number_of_agents-1;
            end
            if strcmp(app.ClubCongestion.Value,'exponential') || strcmp(app.IndividualCongestion.Value,'exponential')
                congestionGUIGen(app.myConfig);
            else
                app.myConfig.Model.club_congestion_delta = 0;
                app.myConfig.Model.club_congestion_a = 0;
                app.myConfig.Model.individual_congestion_delta = 0;
                app.myConfig.Model.individual_congestion_a = 0;
                GUIgenetic(app.myConfig);
            end
            closereq;
        end

        % Value changed function: ModelT
        function ModelTValueChanged(app, event)
            value = app.ModelT.Value;
            if strcmp(value,'First')
                app.IndividualCongestionTypeDropDownLabel.Enable = 'off';
                app.IndividualCongestion.Enable = 'off';
                app.ClubCongestionTypeDropDownLabel.Enable = 'on';
                app.ClubCongestion.Enable = 'on';
            elseif strcmp(value,'Second')
                app.IndividualCongestionTypeDropDownLabel.Enable = 'on';
                app.IndividualCongestion.Enable = 'on';
                app.ClubCongestionTypeDropDownLabel.Enable = 'off';
                app.ClubCongestion.Enable = 'off';
            else
                app.IndividualCongestionTypeDropDownLabel.Enable = 'on';
                app.IndividualCongestion.Enable = 'on';
                app.ClubCongestionTypeDropDownLabel.Enable = 'on';
                app.ClubCongestion.Enable = 'on';
            end               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.modelLabel = uilabel(app.MainUIFigure);
            app.modelLabel.Text = 'model';
            app.ClubCongestionTypeDropDownLabel = uilabel(app.MainUIFigure);
            app.ClubCongestionTypeDropDownLabel.Text = 'Club Congestion Type';
            app.ClubCongestion = uidropdown(app.MainUIFigure);
            app.ClubCongestion.Items = {'inv', 'exponential', 'zeros', 'one'};
            app.ClubCongestion.Value = 'inv';
            app.IndividualCongestionTypeDropDownLabel = uilabel(app.MainUIFigure);
            app.IndividualCongestionTypeDropDownLabel.Enable = 'off';
            app.IndividualCongestionTypeDropDownLabel.Text = 'Individual Congestion Type';
            app.IndividualCongestion = uidropdown(app.MainUIFigure);
            app.IndividualCongestion.Items = {'inv', 'exponential', 'zeros', 'one'};
            app.IndividualCongestion.Enable = 'off';
            app.IndividualCongestion.Value = 'inv';
            app.GeodesicDistanceEditFieldLabel = uilabel(app.MainUIFigure);
            app.GeodesicDistanceEditFieldLabel.Text = 'Geodesic Distance';
            app.GeodesicDistance = uieditfield(app.MainUIFigure, 'numeric');
            app.GeodesicDistance.Limits = [1 Inf];
            app.GeodesicDistance.Value = Inf;
            app.ProcessingLabel = uilabel(app.MainUIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.ClubwiseStabilityDropDownLabel = uilabel(app.MainUIFigure);
            app.ClubwiseStabilityDropDownLabel.Text = 'Clubwise Stability';
            app.ClubwiseStability = uidropdown(app.MainUIFigure);
            app.ClubwiseStability.Items = {'open', 'close'};
            app.ClubwiseStability.Value = 'open';
            app.EnvironmentLabel = uilabel(app.MainUIFigure);
            app.EnvironmentLabel.Text = 'Environment';
            app.NumberOfAgentsEditFieldLabel = uilabel(app.MainUIFigure);
            app.NumberOfAgentsEditFieldLabel.Text = 'Number Of Agents';
            app.NumberOfAgents = uieditfield(app.MainUIFigure, 'numeric');
            app.NumberOfAgents.Limits = [3 25];
            app.NumberOfAgents.Value = 6;
            app.OKButton = uibutton(app.MainUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MembershipCostEditFieldLabel = uilabel(app.MainUIFigure);
            app.MembershipCostEditFieldLabel.Text = 'Membership Cost';
            app.MembershipCost = uieditfield(app.MainUIFigure, 'numeric');
            app.MembershipCost.Limits = [0 Inf];
            app.ModelTypeLabel = uilabel(app.MainUIFigure);
            app.ModelTypeLabel.Text = 'Model Type';
            app.ModelT = uidropdown(app.MainUIFigure);
            app.ModelT.Items = {'First', 'Second', 'Hybrid'};
            app.ModelT.ValueChangedFcn = createCallbackFcn(app, @ModelTValueChanged, true);
            app.ModelT.Value = 'First';
            app.GenerationsLabel = uilabel(app.MainUIFigure);
            app.GenerationsLabel.Text = 'Generations';
            app.genNumber = uieditfield(app.MainUIFigure, 'numeric');
            app.genNumber.Limits = [1 Inf];
            app.genNumber.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainGuiGen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end