classdef mapGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapFigure                      matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        AddClub                        matlab.ui.control.Button
        OK                             matlab.ui.control.Button
        LoadMatrixFileLabel            matlab.ui.control.Label
        LoadFile                       matlab.ui.control.Button
        SetSpecialMatrixDropDownLabel  matlab.ui.control.Label
        SpMatrixDD                     matlab.ui.control.DropDown
        LoadMatrix                     matlab.ui.control.Button
        Remove                         matlab.ui.control.Label
        RemoveOption                   matlab.ui.control.DropDown
        RemoveButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        myconfig
        nAgents % Description
        checkCell % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
           app.myconfig = config;
           app.nAgents = config.Environment.number_of_agents;
           tmp = cell(1,app.nAgents);
           app.checkCell = cell(1,app.nAgents);
           t = app.MapFigure.Position(2) + app.MapFigure.Position(4)-130;
           for i = 1:app.nAgents
               tmp{i} = ['Agent ',num2str(i)];
               app.checkCell{i} = uicheckbox(app.MapFigure);
               app.checkCell{i}.Position = [422 t 71 17];
               t = t-25;
               app.checkCell{i}.Text = ['Agent ',num2str(i)];   
           end
           app.UITable.RowName = tmp;
           app.UITable.Data = zeros(app.nAgents,1);
           app.AddClub.Position(2) = t - 35;
           app.AddClub.Position(1) = 422;
           app.UITable.Position(2) = max(app.AddClub.Position(2),app.LoadFile.Position(2)+40);
           app.UITable.Position(4) = 17 + app.checkCell{1}.Position(2)- app.UITable.Position(2);
           app.SpMatrixDD.Items = createMatOptions(app.nAgents);
 
        end

        % Button pushed function: AddClub
        function AddClubButtonPushed(app, event)
            club = zeros(app.nAgents,1);
            for i = 1:app.nAgents
                club(i) = app.checkCell{i}.Value;
                app.checkCell{i}.Value = false;
            end
            if sum(club) <= 1 
                msgbox('Invalid Usage - Creating a club with one agent or less')
                return
            end
            if any(all(app.UITable.Data == club))
                msgbox('There is an identical club in the current environment');
                return
            end
            if size(app.UITable.Data,2) == 1 && all(app.UITable.Data == zeros(app.nAgents,1))
                app.UITable.Data = club;
            else
                app.UITable.Data = [app.UITable.Data,club];
                app.UITable.ColumnName{size(app.UITable.Data,2)} = num2str(size(app.UITable.Data,2));
            end
            app.RemoveOption.Items = app.UITable.ColumnName;
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            f = waitbar(.33,'Processing the Environment');
            [stability, resultMessage2] = AnalyzeConfig(app.myconfig, logical(app.UITable.Data));
            waitbar(.8,f,'Setting the Output File');
            resultMessage1 = ['The environment is '];
            if stability
                resultMessage = [{resultMessage1} 'STABLE' , {[]}];
            else
                resultMessage = [resultMessage1 'UNSTABLE' ,...
                    resultMessage2];
            end
            if app.myconfig.Model.geodesic_distance == inf
                app.myconfig.Model.geodesic_distance = app.nAgents-1;
            end
            app.myconfig.Environment.number_of_clubs = size(app.UITable.Data,2);
            app.myconfig.Processing = rmfield(app.myconfig.Processing,'processing_type');
            app.myconfig.Processing = rmfield(app.myconfig.Processing,'max_number_of_generations');
            params = config2cell(app.myconfig);
            params(2:2+app.nAgents-1,end+2) = app.UITable.RowName;
            start = size(params,2) + 1;
            params(1,start:start+size(app.UITable.ColumnName,1)-1) = app.UITable.ColumnName;
            params(2:(2+app.nAgents-1),start:start+size(app.UITable.ColumnName,1)-1) = num2cell(app.UITable.Data);
            params(4+app.nAgents,start-1:start+1) = resultMessage; 
            [file,path] = uiputfile('.xlsx');
            xlwriteCurrentSetupReport3([path,file], params, 1, app.nAgents, size(app.UITable.Data,2));
            close(f)
            closereq;
        end

        % Button pushed function: LoadFile
        function LoadFileButtonPushed(app, event)
            map = readFileGUI;
            if isempty(map)
                return
            end
            if app.nAgents ~= size(map,1)
                msgbox('Invalid Usage - enviroment size must be compatible with the original parameters');
                return
            end
            app.UITable.Data = map;
            col = {};
            for i = 1:size(map,2)
                col{i} = num2str(i);
            end
            app.UITable.ColumnName = col;
            app.RemoveOption.Items = app.UITable.ColumnName;
        end

        % Button pushed function: LoadMatrix
        function LoadMatrixButtonPushed(app, event)
            map = GuiSmaps(app.nAgents,app.SpMatrixDD.Value);
            app.UITable.Data = map;
            col = {};
            for i = 1:size(map,2)
                col{i} = num2str(i);
            end
            app.UITable.ColumnName = col;
            app.RemoveOption.Items = app.UITable.ColumnName;    
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if strcmp(app.RemoveOption.Value,'None')
                return
            end
            val = str2double(app.RemoveOption.Value);
            app.UITable.Data(:,val) = [];
            app.UITable.ColumnName(length(app.UITable.ColumnName)) = [];
            app.RemoveOption.Items = app.UITable.ColumnName;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapFigure = uifigure;
            app.MapFigure.Name = 'Membership Map Editing';
            app.UITable = uitable(app.MapFigure);
            app.UITable.ColumnName = {'1'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AddClub = uibutton(app.MapFigure, 'push');
            app.AddClub.ButtonPushedFcn = createCallbackFcn(app, @AddClubButtonPushed, true);
            app.AddClub.Text = 'Add Club';
            app.OK = uibutton(app.MapFigure, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'OK';
            app.LoadMatrixFileLabel = uilabel(app.MapFigure);
            app.LoadMatrixFileLabel.Text = 'Load Matrix File';
            app.LoadFile = uibutton(app.MapFigure, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFile.Text = 'Load';
            app.SetSpecialMatrixDropDownLabel = uilabel(app.MapFigure);
            app.SetSpecialMatrixDropDownLabel.Text = 'Set Special Matrix';
            app.SpMatrixDD = uidropdown(app.MapFigure);
            app.SpMatrixDD.Items = {'All Pairs', '2-Star', 'Option 3', 'Option 4'};
            app.SpMatrixDD.Value = 'All Pairs';
            app.LoadMatrix = uibutton(app.MapFigure, 'push');
            app.LoadMatrix.ButtonPushedFcn = createCallbackFcn(app, @LoadMatrixButtonPushed, true);
            app.LoadMatrix.Text = 'Load';
            app.Remove = uilabel(app.MapFigure);
            app.Remove.Text = 'Remove Club';
            app.RemoveOption = uidropdown(app.MapFigure);
            app.RemoveOption.Items = {'None'};
            app.RemoveOption.Value = 'None';
            app.RemoveButton = uibutton(app.MapFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mapGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapFigure)
        end
    end
end